classdef plot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PathLabel          matlab.ui.control.Label
        NodesSpinner       matlab.ui.control.Spinner
        NodesSpinnerLabel  matlab.ui.control.Label
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %sub_points = [];
        Slider = [];
    end
    
    methods (Access = private)
        
        function results = graph(app)
            show_mid_points = true;
            show_path = true;
            resolution = 0.01;
            p = get(app.Slider,'Value');

            data = app.UITable.Data;
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')

            scatter(app.UIAxes,data(:,1), data(:,2))
            
            ln = length(data);
            for jj = 1:ln
                j = ln -jj;
                old_data = data;
                data=zeros(j, 2);
                for i = 1:j
                    data(i,:) = old_data(i+1,:).*p + old_data(i,:).*(1-p);
                end
                if(j == 1 )
                    scatter(app.UIAxes,data(:,1), data(:,2), 'filled')
                elseif(show_mid_points)
                   scatter(app.UIAxes,data(:,1), data(:,2))
                end

                
            end
            if(show_path)
                data = app.UITable.Data;
                t = 0:resolution/p:p;

                n = length(data);
                path_a = zeros(1,length(t));
                path_b = zeros(1,length(t));
                for i = 1:n
                    c = (nchoosek(n, i)*((1-t).^(n-i)).*t.^i);
                    path_a = path_a + c*data(i,1);
                    path_b = path_b + c*data(i,2);
                    
                end
                plot(app.UIAxes,path_a, path_b)
            end

            hold(app.UIAxes, 'off')
        end
        
        function results = rescale(app)
            data = app.UITable.Data;
            
            min_x=min(data(:,1));
            max_x=max(data(:,1));
            l_x = abs(max_x-min_x);
            
            min_y=min(data(:,2));
            max_y=max(data(:,2));
            l_y = abs(max_y-min_y);

            xlim(app.UIAxes, [(min_x-0.1*l_x)  (max_x+0.1*l_x)])
            ylim(app.UIAxes, [(min_y-0.1*l_y)  (max_y+0.1*l_y)])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = [0,0;1,1];
            app.UITable.ColumnEditable = true;

            F = app.UIFigure;%figure();
            app.Slider = uicontrol(F,'Style','slider');
            set(app.Slider,'OuterPosition',[50,50,200,20]);
            addlistener(app.Slider, 'Value', 'PostSet',@(~, ~) graph(app));
            %addlistener(app.PathSlider,'ValueChangingFcn',@(hObject, event) graph(app));


            graph(app);
        end

        % Value changed function: NodesSpinner
        function NodesSpinnerValueChanged(app, event)
            value = app.NodesSpinner.Value;
            %app.UITable.Data = [0];

            old = app.UITable.Data;
            app.UITable.Data = zeros(value,2);
            
            for i = 1:value
                if i <= length(app.UITable.Data) && i <= length(old) 
                    app.UITable.Data(i,:) = old(i,:);
                end

            end
            rescale(app);
            graph(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            rescale(app);
            graph(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.NodesSpinnerLabel = uilabel(app.UIFigure);
            app.NodesSpinnerLabel.Text = '# Nodes';
            app.NodesSpinner = uispinner(app.UIFigure);
            app.NodesSpinner.Limits = [0 Inf];
            app.NodesSpinner.ValueChangedFcn = createCallbackFcn(app, @NodesSpinnerValueChanged, true);
            app.NodesSpinner.Value = 2;
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path %';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end